classdef GuiLogger < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        SavecontCheckBox       matlab.ui.control.CheckBox
        SavelogButton          matlab.ui.control.Button
        ClearlogButton         matlab.ui.control.Button
        Axes                   matlab.ui.control.UIAxes
        LogfileEditFieldLabel  matlab.ui.control.Label
        LogfileEditField       matlab.ui.control.EditField
        PeriodsLabel           matlab.ui.control.Label
        MeasperiodsEditField   matlab.ui.control.NumericEditField
        MeasButton             matlab.ui.control.StateButton
        MeasurementLabel       matlab.ui.control.Label
    end


    properties (Access = public)
        name = 'Logger' % Name of the logger window
        linked_elem_list={}; % List of elements updated by updateGui
        Lg % Logger object
    end

    properties (Access = private)
        Llh % Listener to the logger NewData event 
    end

    methods (Access = private)
    
        function createControlLinks(app)
            app.LogfileEditField.Tag = 'save_file';
            app.linked_elem_list = [app.linked_elem_list, app.LogfileEditField];
            
            app.SavecontCheckBox.Tag = 'save_cont';
            app.linked_elem_list = [app.linked_elem_list, app.SavecontCheckBox];
            
            app.MeasperiodsEditField.Tag = 'MeasTimer.Period';
            app.linked_elem_list = [app.linked_elem_list, app.MeasperiodsEditField];
            
            app.MeasButton.Tag = 'MeasTimer.Running';
            addprop(app.MeasButton,'OutputProcessingFcn');
            app.MeasButton.OutputProcessingFcn = @(str)setMeasButton(app,str);
            app.linked_elem_list = [app.linked_elem_list, app.MeasButton];
        end
        
        function updatePlot(app)
            cla(app.Axes)
            hold(app.Axes,'on')
            mdata= cell2mat(app.Lg.data);
            [~, m] = size(mdata);
            for i=1:m
                plot(app.Axes, app.Lg.timestamps, mdata(:,i))
            end
            hold(app.Axes,'off')
        end
    
        function val = setMeasButton(app, str)
            if strcmpi(str,'on') 
                val = 1;
                app.MeasButton.Text = 'Stop';
            else 
                val = 0;
                app.MeasButton.Text = 'Start';
            end
        end
    
        function newDataCallback(app,src,event)
            updatePlot(app);
        end
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, varargin)
            p=inputParser();
            p.KeepUnmatched = true;
            filt_varargin = parseClassInputs(p, app, varargin{:});
            
            if ismember('Lg',p.UsingDefaults)
                % Create a logger, if it was not supplied externally
                app.Lg = MyLogger(filt_varargin{:});
            end
            
            app.Llh = addlistener(app.Lg, 'NewData', @app.newDataCallback);
            
            createControlLinks(app);
            updateGui(app, app.Lg);
        end

        % Value changed function: LogfileEditField
        function LogfileEditFieldValueChanged(app, event)
            value = app.LogfileEditField.Value;
            app.Lg.save_file = value;
        end

        % Value changed function: MeasButton
        function MeasButtonValueChanged(app, event)
            value = app.MeasButton.Value;
            if value
                start(app.Lg)
                app.MeasperiodsEditField.Editable='off';
            else
                stop(app.Lg)
                app.MeasperiodsEditField.Editable='on';
            end
            updateGui(app, app.Lg);
        end

        % Value changed function: SavecontCheckBox
        function SavecontCheckBoxValueChanged(app, event)
            value = app.SavecontCheckBox.Value;
            app.Lg.save_cont = value;
        end

        % Button pushed function: ClearlogButton
        function ClearlogButtonPushed(app, event)
            clearLog(app.Lg);
            updatePlot(app);
        end

        % Button pushed function: SavelogButton
        function SavelogButtonPushed(app, event)
            saveLog(app.Lg);
        end

        % Value changed function: MeasperiodsEditField
        function MeasperiodsEditFieldValueChanged(app, event)
            value = app.MeasperiodsEditField.Value;
            app.Lg.MeasTimer.Period = value;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.SavecontCheckBox = uicheckbox(app.UIFigure);
            app.SavecontCheckBox.ValueChangedFcn = createCallbackFcn(app, @SavecontCheckBoxValueChanged, true);
            app.SavecontCheckBox.Text = 'Save continuously';
            app.SavelogButton = uibutton(app.UIFigure, 'push');
            app.SavelogButton.ButtonPushedFcn = createCallbackFcn(app, @SavelogButtonPushed, true);
            app.SavelogButton.Text = 'Save log';
            app.ClearlogButton = uibutton(app.UIFigure, 'push');
            app.ClearlogButton.ButtonPushedFcn = createCallbackFcn(app, @ClearlogButtonPushed, true);
            app.ClearlogButton.Text = 'Clear log';
            app.Axes = uiaxes(app.UIFigure);
            title(app.Axes, 'Title')
            xlabel(app.Axes, 'X')
            ylabel(app.Axes, 'Y')
            app.LogfileEditFieldLabel = uilabel(app.UIFigure);
            app.LogfileEditFieldLabel.Text = 'Log file';
            app.LogfileEditField = uieditfield(app.UIFigure, 'text');
            app.LogfileEditField.ValueChangedFcn = createCallbackFcn(app, @LogfileEditFieldValueChanged, true);
            app.LogfileEditField.Value = 'M:\Measurement Campaigns\2018-07-13 Pressure Log';
            app.PeriodsLabel = uilabel(app.UIFigure);
            app.PeriodsLabel.Text = 'Period, s';
            app.MeasperiodsEditField = uieditfield(app.UIFigure, 'numeric');
            app.MeasperiodsEditField.ValueChangedFcn = createCallbackFcn(app, @MeasperiodsEditFieldValueChanged, true);
            app.MeasButton = uibutton(app.UIFigure, 'state');
            app.MeasButton.ValueChangedFcn = createCallbackFcn(app, @MeasButtonValueChanged, true);
            app.MeasButton.Text = 'Start';
            app.MeasurementLabel = uilabel(app.UIFigure);
            app.MeasurementLabel.Text = 'Measurement';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GuiLogger(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end